classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        PLOTButton                 matlab.ui.control.Button
        WhatvisualisedButtonGroup  matlab.ui.container.ButtonGroup
        FrontBackButton            matlab.ui.control.ToggleButton
        LateralButton              matlab.ui.control.ToggleButton
        TopBottonButton            matlab.ui.control.ToggleButton
        BonesButton                matlab.ui.control.ToggleButton
        FigureButton               matlab.ui.control.ToggleButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            path=uigetdir('SEO');
            [V,spatial,~]=dicomreadVolume(fullfile(path));
            V=squeeze(V);
            spatial;

            if app.FigureButton.Value==true
                figure
                vol1=volshow(V);
                vol1.BackgroundColor='white';
                vol1.ScaleFactors=[spatial.PixelSpacings(1, 1), spatial.PixelSpacings(1, 2)  abs(spatial.PatientPositions(1,3)-spatial.PatientPositions(3,3))];
                vol1.Isovalue=0.13;
                vol1.Renderer='Isosurface';

            elseif app.BonesButton.Value==true
                figure
                vol2=volshow(V);
                vol2.BackgroundColor='white';
                vol2.ScaleFactors=[spatial.PixelSpacings(1, 1), spatial.PixelSpacings(1, 2)  abs(spatial.PatientPositions(1,3)-spatial.PatientPositions(3,3))];
                vol2.Isovalue=0.274;
                vol2.Renderer='Isosurface';

            elseif app.TopBottonButton.Value==true
                figure
                for i=1:246
                    subplot(16,16,i)
                    XY = V(:,:,i);
                    imshow(XY,[],'Border','tight');
                end

                figure
                vol3=sliceViewer(V);

            elseif app.LateralButton.Value==true
                figure
                for ii=1:512
                    subplot(23,23,ii)
                    XZ = squeeze(V(ii,:,:));
                    imshow(XZ,[],'Border','tight');
                end
                
            elseif app.FrontBackButton.Value==true
                figure
                for iii=1:512
                    subplot(23,23,iii)
                    YZ = squeeze(V(:,iii,:));
                    imshow(YZ,[],'Border','tight');
                end
            end


        end

        % Selection changed function: WhatvisualisedButtonGroup
        function WhatvisualisedButtonGroupSelectionChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhatvisualisedButtonGroup = uibuttongroup(app.UIFigure);
            app.WhatvisualisedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WhatvisualisedButtonGroupSelectionChanged, true);
            app.WhatvisualisedButtonGroup.Title = 'What visualised';
            app.FigureButton = uitogglebutton(app.WhatvisualisedButtonGroup);
            app.FigureButton.Text = 'Figure';
            app.FigureButton.Value = true;
            app.BonesButton = uitogglebutton(app.WhatvisualisedButtonGroup);
            app.BonesButton.Text = 'Bones';
            app.TopBottonButton = uitogglebutton(app.WhatvisualisedButtonGroup);
            app.TopBottonButton.Text = 'Top-Botton';
            app.LateralButton = uitogglebutton(app.WhatvisualisedButtonGroup);
            app.LateralButton.Text = 'Lateral';
            app.FrontBackButton = uitogglebutton(app.WhatvisualisedButtonGroup);
            app.FrontBackButton.Text = 'Front-Back';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Diapositiva1.JPG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end